classdef track_anal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        btnGSEROpt             matlab.ui.control.Button
        lblStatus              matlab.ui.control.Label
        TrackfilesmatLabel     matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        btnRemove              matlab.ui.control.Button
        btnLucy                matlab.ui.control.Button
        sldLagTime             matlab.ui.control.Slider
        LagtimesLabel          matlab.ui.control.Label
        bgpPlotData            matlab.ui.container.ButtonGroup
        optSPMSD               matlab.ui.control.RadioButton
        optVanHove             matlab.ui.control.RadioButton
        optAlpha_2             matlab.ui.control.RadioButton
        optGSER                matlab.ui.control.RadioButton
        optMPMSD               matlab.ui.control.RadioButton
        btnCalculate           matlab.ui.control.Button
        tblCurrentTracks       matlab.ui.control.Table
        btnAddTrack            matlab.ui.control.Button
        pnlParameters          matlab.ui.container.Panel
        edtResolution          matlab.ui.control.NumericEditField
        ResolutionmpixelLabel  matlab.ui.control.Label
        edtShutterTime         matlab.ui.control.NumericEditField
        ShuttertimesLabel      matlab.ui.control.Label
        edtParticleRadius      matlab.ui.control.NumericEditField
        ParticleradiusmLabel   matlab.ui.control.Label
        edtTemperature         matlab.ui.control.NumericEditField
        TemperatureCLabel      matlab.ui.control.Label
        axePlot                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentPath=pwd % Description
        msd_i
        msd
        G

        alpha_2
        alpha_2_i
        van_Hove
        p_D
        P_D
        van_Hove_rec=[]
        dtP
        CurrentSelectedRow % Description
        GSER_dialogBox
        Lucy_dialogBox
    end

    methods (Access = public)

        function GSER_result(app,G)

            app.G=G;
            if app.bgpPlotData.SelectedObject==app.optGSER
                cla(app.axePlot,'reset');

                app.axePlot.Box='on';

                loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);

                app.axePlot.XLabel.String='angular frequency (rad/s)';
                app.axePlot.YLabel.String='Moduli (Pa)';
                app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                app.axePlot.XMinorTick='on';
                app.axePlot.YMinorTick='on';

                lg=legend(app.axePlot);

                lg.Interpreter='latex';
                lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                lg.Location='best';
                lg.Color='none';


            end
        end

        function Lucy_result(app,p_D,P_D,van_Hove_rec,dtP)
            if ~isempty(p_D)
                app.p_D=p_D;
                app.P_D=P_D;
                app.van_Hove_rec=van_Hove_rec;
                app.dtP=dtP;

                app.sldLagTime.MinorTicks=log10(dtP);
                if app.bgpPlotData.SelectedObject==app.optVanHove
                    dt=10.^app.sldLagTime.Value;
                    [~,ind]=min(abs(app.msd(:,1)-dt));

                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot)
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot)

                    [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                    if ~isempty(app.van_Hove_rec)& ind2==ind
                        hold(app.axePlot,'on');
                        plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                        hold(app.axePlot,'off');
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Value changed function: edtResolution
        function edtResolutionValueChanged(app, event)
            value = app.edtResolution.Value;
            if isnan(value)||value<=0
                errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                app.edtResolution.Value=0.1083266701;
                return
            end
        end

        % Value changed function: edtTemperature
        function edtTemperatureValueChanged(app, event)
            value = app.edtTemperature.Value;
            if isnan(value)||value<=0
                errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                app.edtTemperature.Value=30;
                return
            end
        end

        % Value changed function: edtParticleRadius
        function edtParticleRadiusValueChanged(app, event)
            value = app.edtParticleRadius.Value;
            if isnan(value)||value<=0
                errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                app.edtParticleRadius.Value=0.1;
                return
            end
        end

        % Value changed function: edtShutterTime
        function edtShutterTimeValueChanged(app, event)
            value = app.edtShutterTime.Value;
            if isnan(value)||value<=0
                errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                app.edtShutterTime.Value=0.020142000000000;
                return
            end
        end

        % Button pushed function: btnAddTrack
        function btnAddTrackButtonPushed(app, event)
            [file,path]=uigetfile('*.mat','Select tr.mat file',app.CurrentPath,'MultiSelect','on');


            % Convert the warning event into error that can be trapped by a
            % try-catch cycle.
            % More about the 'error' state of warning function see:
            % https://tinyurl.com/4ekrcdu6
            % warning('error','MATLAB:load:variableNotFound');

            switch class(file)
                case 'char' % a single file is selected
                    blnSuccess=true;
                    % set the initial state as true and let subsequent errors
                    % change it into false

                    % get a list of variables
                    variable_list=who('-file',fullfile(path,file));

                    % check exisitence of tr
                    if ~isempty(find(ismember(variable_list,'tr'), 1))
                        tr=load(fullfile(path,file),'tr');

                    else
                        blnSuccess=false;
                        uialert(app.UIFigure,'No track data in this file.','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end

                    if blnSuccess

                        t=app.tblCurrentTracks.Data;
                        tr=tr.tr;
                        tr=sortrows(tr,[4,3]);

                        N_tracks=tr(end,4);

                        if isempty(t)
                            t={path,file,num2str(N_tracks)};
                            app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                        else
                            t=[t;{path,file,num2str(N_tracks)}];

                            % check duplicated opened file
                            dup=t;
                            [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                            t=dup(idx,:);
                        end
                        app.tblCurrentTracks.Data=t;
                        app.CurrentPath=path;


                    end

                    % load other parameters if exists
                    if ~isempty(find(ismember(variable_list,'dt'), 1))
                        dt=load(fullfile(path,file),'dt');
                        if ~isempty(dt.dt)
                            app.edtShutterTime.Value=dt.dt;
                        end
                    end

                    if ~isempty(find(ismember(variable_list,'PixelSize'), 1))

                        PixelSize=load(fullfile(path,file),'PixelSize');
                        if ~isempty(PixelSize.PixelSize)
                            app.edtResolution.Value=PixelSize.PixelSize;
                        end
                    end


                case 'cell' % multiple files are selected

                    no_track_list=[];
                    for i=1:length(file)
                        blnSuccess=true;
                        variable_list=who('-file',fullfile(path,file{i}));

                        if ~isempty(find(ismember(variable_list,'tr'), 1))
                            tr=load(fullfile(path,file{i}),'tr');
                        else
                            blnSuccess=false;
                            no_track_list=[no_track_list;fullfile(path,file{i})];
                        end

                        if blnSuccess

                            t=app.tblCurrentTracks.Data;
                            tr=tr.tr;
                            tr=sortrows(tr,[4,3]);

                            N_tracks=tr(end,4);

                            if isempty(t)
                                t={path,file{i},num2str(N_tracks)};
                                app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                            else
                                t=[t;{path,file{i},num2str(N_tracks)}];

                                % check duplicated opened file
                                dup=t;
                                [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                                t=dup(idx,:);
                            end
                            app.tblCurrentTracks.Data=t;

                        end
                        app.CurrentPath=path;

                        % load other parameters if exists
                        if ~isempty(find(ismember(variable_list,'dt'), 1))
                            dt=load(fullfile(path,file{i}),'dt');
                            app.edtShutterTime.Value=dt.dt;
                        end

                        if ~isempty(find(ismember(variable_list,'PixelSize'), 1))
                            PixelSize=load(fullfile(path,file{i}),'PixelSize');
                            if ~isempty(PixelSize.PixelSize)

                                app.edtResolution.Value=PixelSize.PixelSize;
                            end
                        end


                    end
                    if ~isempty(no_track_list)
                        message=["No track data in file(s):";no_track_list];
                        uialert(app.UIFigure,message,'Error','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end
                case 'double' % no file is selected
                    uialert(app.UIFigure,'No file selected.','Canceled','Icon','info','Modal',true);
                    figure(app.UIFigure)
                    blnSuccess=false;
                    % app.CurrentPath=path;
            end


            % switch class(file)
            %     case 'char' % single file selected
            %
            %         blnSuccess=true;
            %         % set the initial state as true and let subsequent errors
            %         % change it into false
            %
            %         % see if the opened .mat file contains the variable tr
            %         try
            %             tr=load([path file],'tr');
            %         catch ME
            %             uialert(app.UIFigure,ME.message,'Error','Icon','error')
            %             blnSuccess=false;
            %         end
            %         % Set the state of warning event back to normal
            %         warning('on','MATLAB:load:variableNotFound');
            %
            %         % update tblCurrentTracks
            %         if blnSuccess
            %
            %             t=app.tblCurrentTracks.Data;
            %             tr=tr.tr;
            %             tr=sortrows(tr,[4,3]);
            %
            %             N_tracks=tr(end,4);
            %
            %             if isempty(t)
            %                 t={path,file,num2str(N_tracks)};
            %                 app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};
            %
            %             else
            %                 t=[t;{path,file,num2str(N_tracks)}];
            %
            %                 % check duplicated opened file
            %                 dup=t;
            %                 [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
            %                 t=dup(idx,:);
            %             end
            %             app.tblCurrentTracks.Data=t;
            %             app.CurrentPath=path;
            %         end
            %
            %     case 'cell' % multiple files selected
            %         for i=1:length(file)
            %             % do the same as the case for single file selected
            %
            %             % see if the opened .mat file contains the variable tr
            %             blnSuccess=true;
            %             try
            %                 tr=load(fullfile(path,file{i}),'tr');
            %             catch ME
            %                 uialert(app.UIFigure,ME.message,'Error','Icon','error')
            %                 blnSuccess=false;
            %             end
            %             % Set the state of warning event back to normal
            %             warning('on','MATLAB:load:variableNotFound');
            %
            %             % update tblCurrentTracks
            %             if blnSuccess
            %
            %                 t=app.tblCurrentTracks.Data;
            %                 tr=tr.tr;
            %                 tr=sortrows(tr,[4,3]);
            %
            %                 N_tracks=tr(end,4);
            %
            %                 if isempty(t)
            %                     t={path,file{i},num2str(N_tracks)};
            %                     app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};
            %
            %                 else
            %                     t=[t;{path,file{i},num2str(N_tracks)}];
            %
            %                     % check duplicated opened file
            %                     dup=t;
            %                     [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
            %                     t=dup(idx,:);
            %                 end
            %                 app.tblCurrentTracks.Data=t;
            %             end
            %             app.CurrentPath=path;
            %         end
            %     case 'double' % no file selected
            %         uialert(app.UIFigure,'No track selected.','Canceled','Icon','info');
            %         blnSuccess=false;
            %         warning('on','MATLAB:load:variableNotFound');
            % end

            figure(app.UIFigure);


        end

        % Button pushed function: btnCalculate
        function btnCalculateButtonPushed(app, event)
            % allocate final data variables
            app.msd=[];
            app.msd_i=[];
            app.G=[];
            app.alpha_2=[];
            app.alpha_2_i=[];
            app.van_Hove=[];
            app.p_D=[];
            app.P_D=[];
            app.van_Hove_rec=[];
            app.dtP=[];


            % read raw data and parameters
            dt=app.edtShutterTime.Value;
            res=app.edtResolution.Value*1e-6;
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;
            tt=app.tblCurrentTracks.Data;
            if isempty(tt)
                errordlg('No tracks added.','Blank data','modal')
                return
            end

            drawnow
            [N,~]=size(tt); % N is the number of track files
            tr_list=cell(N,1); % pre-allocate
            lastNTrack=0;
            for l=1:N
                tr=load([tt{l,1} tt{l,2}],'tr');
                tr=tr.tr;

                % regard multiple track file as one with extra tracks.
                % effectively adding to the tr_id's.
                tr(:,4)=tr(:,4)+lastNTrack;
                tr=sortrows(tr,[4,3]);

                % separate by tracks into cell array |tr_list|
                ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                tr_temp=cell(length(ind(:,1)),1);
                for i=1:length(ind(:,1))
                    tr_temp{i}=tr(ind(i,1):ind(i,2),:);
                end % i
                tr_list{l}=tr_temp;
                lastNTrack=lastNTrack+length(tr_temp);
            end

            N_tracks_per_tr=arrayfun(@(i) length(tr_list{i}),(1:length(tr_list))','UniformOutput',1);

            dxy=cell(sum(N_tracks_per_tr),1);
            N_tracks_proc=0;
            for l=1:N % loop through number of track files
                tr_temp=tr_list{l};
                % tr_temp have been separated into different tr_id's

                %% drift estimation
                % Here, 'drift' refers to the common motion among all
                % particles. Viewing all particles as a mechanical system,
                % the rigid-body motion of the particle related to the
                % field of observation is canceled. However, 'drift' may
                % refer to the characteristics of the random walk of each
                % particle. This type of drift is untreated in the present
                % routine.


                % calculate the displacements between adjacent frames, and
                % label them by fr_lags and tr_id's
                drift_temp=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tr_temp,'UniformOutput',0);

                % now each cell of drift_temp: [dx,dy,fr_lags,starting
                % fr_id, tr_id].

                drift_temp=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift_temp,'UniformOutput',0);
                drift_temp=sortrows(cell2mat(drift_temp),[3,4]);

                % now each cell of drift_temp contains displacement of a
                % particle between two adjacent frames starting at each
                % frame. [dx, dy, starting fr_id, tr_id]


                % Next, separate drift_temp into cell array by starting
                % fr_id, and average the displacements of all particles in each
                % frame.
                ind=[[1;find(diff(drift_temp(:,3))~=0)+1],[find(diff(drift_temp(:,3))~=0);length(drift_temp(:,3))]];
                drift_temp=cell2mat(arrayfun(@(i,j) [mean(drift_temp(i:j,1:2),1),drift_temp(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));
                % now drift_temp [mean_dx, mean_dy, starting fr_id]. For no
                % -drift condition mean_dx=mean_dy=0. So non-zero values of
                % them quantify the extent of drifting. Such values are
                % different for different starting fr_id's, which is
                % regarded as an random walk of pure drift


                drift_temp(:,1:2)=cumsum(drift_temp(:,1:2),1);
                % cumsum yield the cumulated drift displacement till each
                % frame. Particle positions in each frame should substract
                % the cumulated drift displacement till the same frame for
                % drift cancelling.

                dxy_temp=cell(length(tr_temp),1);

                for j=1:length(tr_temp)
                    % loop through number of tracks under the given track
                    % file
                    t=tr_temp{j};

                    % drift canceling
                    if length(tr_temp)>1
                        % drift canceling is not needed for single particle case
                        [~,ia,ib]=intersect(t(:,3)-1,drift_temp(:,3));
                        t(ia,:)=[t(ia,1:2)-drift_temp(ib,1:2),t(ia,3:4)];
                    end

                    % lag-time-dependent analysis
                    [p,q]=meshgrid(1:length(t(:,1)),1:length(t(:,1)));
                    pairs=[p(:),q(:)];
                    pairs(pairs(:,1)>=pairs(:,2),:)=[];

                    if ~isempty(pairs)
                        % One cannot calculate 'displacement' out of one
                        % position. Tracks with only one point produce
                        % empty |pairs|, and hence are omitted.

                        t=sortrows([t(pairs(:,2),1)-t(pairs(:,1),1),t(pairs(:,2),2)-t(pairs(:,1),2),...
                            t(pairs(:,2),3)-t(pairs(:,1),3),ones(length(pairs(:,1)),1).*t(1,4)],[3,4]);
                        % now |t| contains [dx,dy,fr_lag, tr_id]

                        % separate |t| by fr_lag into cell array |dxy_temp|
                        ind=[[1;find(diff(t(:,3))~=0)+1],[find(diff(t(:,3))~=0);length(t(:,3))]];
                        temp_cell=cell(length(ind(:,1)),1);
                        for k=1:length(ind(:,1))
                            temp_cell{k}=t(ind(k,1):ind(k,2),:);
                        end % k
                        dxy_temp{j}=temp_cell;


                        N_tracks_proc=N_tracks_proc+1;
                        app.lblStatus.Text=['tr#' num2str(N_tracks_proc)];
                        drawnow
                    end
                end %j
                dxy(sum(N_tracks_per_tr(1:l-1))+1:sum(N_tracks_per_tr(1:l)))=dxy_temp;


            end %l

            % clean up the empty cells in dxy due to pre-allocation for
            % un-processed tracks.
            ind=cellfun(@(x) ~isempty(x),dxy,'UniformOutput',1);
            dxy=dxy(ind);

            clearvars dxy_temp tr_list tr_temp temp_cell t tr p q pairs drift_temp;

            app.lblStatus.Text='msd_i';
            drawnow
            % single-particle msd
            raw_msd_i=cell(length(dxy),1);
            for i=1:length(dxy) % loop through total number of tracks
                temp=zeros(length(dxy{i}),3); % preallocate
                for j=1:length(dxy{i})
                    % calculate dr^2=dx^2+dy^2
                    temp(j,:)=[dxy{i}{j}(1,3),mean(sum(dxy{i}{j}(:,1:2).^2,2)),dxy{i}{j}(1,4)]; % msd_i of dx
                end
                raw_msd_i{i}=temp;
            end % i
            % now each cell of |raw_msd_i| contains [fr_lag,msd_i,tr_id] of a
            % track. unit of msd: px^2

            % unit conversion
            app.msd_i=cellfun(@(x) [x(:,1).*dt,x(:,2).*res.^2,x(:,3)],raw_msd_i,'UniformOutput',0);
            clearvars raw_msd_i;

            app.lblStatus.Text='alpha_2_i';
            drawnow
            % single-particle alpha_2
            raw_alpha_2_i=cell(length(dxy),1);
            for i=1:length(dxy)
                temp=zeros(length(dxy{i}),4);
                for j=1:length(dxy{i})
                    Nk=length(dxy{i}{j}(:,1));
                    temp(j,:)=[dxy{i}{j}(1,3),kurtosis(dxy{i}{j}(:,1),0,1)-3,...
                        24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3)),...
                        dxy{i}{j}(1,4)]; % alpha_2_i from dx only
                end
                raw_alpha_2_i{i}=temp(~isnan(temp(:,2)),:);
            end

            % separate by fr_lag into cell array ...
            raw_alpha_2_i=sortrows(cell2mat(raw_alpha_2_i),[1,4]);
            ind=[[1;find(diff(raw_alpha_2_i(:,1))~=0)+1],[find(diff(raw_alpha_2_i(:,1))~=0);length(raw_alpha_2_i(:,1))]];

            % ... and average all single-particle alpha_2 and record their std_err
            app.alpha_2_i=cell2mat(arrayfun(@(i,j) [raw_alpha_2_i(i,1).*dt mean(raw_alpha_2_i(i:j,2)),std(raw_alpha_2_i(i:j,2))],...
                ind(:,1),ind(:,2),'UniformOutput',0));
            clearvars raw_alpha_2_i;


            app.lblStatus.Text='msd';
            drawnow

            % combine displacement of different particles
            dxy=sortrows(cell2mat(cellfun(@(x) cell2mat(cellfun(@(y) y ,x,'UniformOutput',0)),dxy,'UniformOutput',0)),[3,4]);

            % separate by fr_lag into cell array ...
            ind=[[1;find(diff(dxy(:,3))~=0)+1],[find(diff(dxy(:,3))~=0);length(dxy(:,3))]];

            % and calculate dx^2, dy^2 and dr^2
            raw_msd=cell2mat(arrayfun(@(i,j) [dxy(i,3),mean(dxy(i:j,1).^2),...
                mean(dxy(i:j,2).^2), mean(sum(dxy(i:j,1:2).^2,2))],ind(:,1),ind(:,2),'UniformOutput',0));
            app.msd=[raw_msd(:,1).*dt,raw_msd(:,2:4).*res.^2]; % unit conversion
            clearvars raw_msd;


            app.lblStatus.Text='alpha_2';
            drawnow

            % calculate alpha_2 only for dx
            raw_alpha_2=cell2mat(arrayfun(@(i,j) [dxy(i,3).*dt,kurtosis(dxy(i:j,1),0,1)-3],ind(:,1),ind(:,2),'UniformOutput',0));
            Nk=ind(:,2)-ind(:,1)+1;
            app.alpha_2=[raw_alpha_2,abs(sqrt(24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3))))];
            clearvars raw_alpha_2;

            app.lblStatus.Text='van_Hove';
            drawnow
            % histogram dx only
            [N,edges]=arrayfun(@(i,j) histcounts(dxy(i:j,1).*res,100,'Normalization','pdf'),ind(:,1),ind(:,2),'UniformOutput',0);
            app.van_Hove=[N,edges];


            app.lblStatus.Text='Done!';
            drawnow
            clearvars dxy;

            % calculate G' and G''
            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);


            % update slider
            app.sldLagTime.Limits=log10([min(app.msd(:,1)),max(app.msd(:,1))]);
            decade_points=ceil(log10(min(app.msd(:,1)))):floor(log10(max(app.msd(:,1))));
            app.sldLagTime.MajorTicks=decade_points;
            app.sldLagTime.MajorTickLabels=arrayfun(@(i) num2str(10^decade_points(i)),1:length(decade_points),'UniformOutput',0);
            app.sldLagTime.MinorTicks=floor(log10(min(app.msd(:,1)))/0.2)*0.2:0.2:ceil(log10(max(app.msd(:,1)))/0.2)*0.2;
            app.sldLagTime.Value=log10(min(app.msd(:,1)));
            drawnow

            % update bpgPlotData
            app.optMPMSD.Value=true;
            drawnow


            % plot MP-MSD by default
            reset(app.axePlot)
            app.axePlot.Box='on';
            loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

            app.axePlot.XLabel.String='lag time (s)';
            app.axePlot.YLabel.String='MSD (m^2\rm)';
            app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2:4),[],'all')))];
            app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            app.axePlot.XMinorTick='on';
            app.axePlot.YMinorTick='on';


            hold(app.axePlot)
            x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
            x2=max(app.msd(:,1));
            y1=interp1(app.msd(:,1),app.msd(:,4),x1);
            y2=y1*x2/x1;
            plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
            lg=legend(app.axePlot);
            lg.Box='off';
            lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
            lg.Location='best';
            lg.Color='none';
            hold(app.axePlot)
            app.optMPMSD.Value=true;


            drawnow


        end

        % Selection changed function: bgpPlotData
        function bgpPlotDataSelectionChanged(app, event)
            selectedButton = app.bgpPlotData.SelectedObject;
            switch selectedButton
                case app.optMPMSD
                    cla(app.axePlot,'reset')

                    legend(app.axePlot,'off');
                    app.axePlot.Box='on';

                    loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

                    app.axePlot.XLabel.String='lag time (s)';
                    app.axePlot.YLabel.String='MSD (m^2\rm)';
                    app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                    app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
                    app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                    app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';


                    hold(app.axePlot)
                    x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
                    x2=max(app.msd(:,1));
                    y1=interp1(app.msd(:,1),app.msd(:,4),x1);
                    y2=y1*x2/x1;
                    plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
                    lg=legend(app.axePlot);
                    lg.Box='off';
                    lg.Color='none';
                    lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
                    lg.Location='best';
                    hold(app.axePlot)

                    app.optMPMSD.Value=true;
                case app.optGSER
                    cla(app.axePlot,'reset');

                    app.axePlot.Box='on';
                    if ~isempty(app.G)
                        loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);


                        app.axePlot.XLabel.String='angular frequency (rad/s)';
                        app.axePlot.YLabel.String='Moduli (Pa)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                        app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                            ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                        app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                            ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';

                        lg=legend(app.axePlot);

                        lg.Interpreter='latex';
                        lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                        lg.Location='best';
                        lg.Box='off';
                        lg.Color='none';
                    end
                case app.optAlpha_2
                    cla(app.axePlot,'reset')

                    app.axePlot.Box='on';


                    line_color=app.axePlot.ColorOrder(1,:);
                    bar_color=rgb2hsv(line_color);

                    bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                    bar_color=hsv2rgb(bar_color);


                    errorbar(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),app.alpha_2_i(:,3),...
                        'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                    hold(app.axePlot,'on')
                    plot(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),'LineWidth',1,'Color',line_color);

                    line_color=app.axePlot.ColorOrder(2,:);
                    bar_color=rgb2hsv(line_color);

                    bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                    bar_color=hsv2rgb(bar_color);

                    errorbar(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),app.alpha_2(:,3),...
                        'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                    plot(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),'LineWidth',1,'Color',line_color);

                    hold(app.axePlot,'off')
                    app.axePlot.XScale='log';
                    app.axePlot.YScale='linear';


                    app.axePlot.XLabel.String='lag time (s)';
                    app.axePlot.YLabel.Interpreter='latex';
                    app.axePlot.YLabel.String='$\alpha_2$ (-)';
                    app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                    %                     app.axePlot.YLim=[floor(min(0,min(app.alpha_2(:,2)))),ceil(max(app.alpha_2(:,2)))];
                    app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                    %                     app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';


                    lg=legend(app.axePlot);
                    lg.String=[{'single'},{'multiple'}];
                    lg.Location='best';
                    lg.Color='none';
                    lg.Box='off';

                case app.optVanHove


                    dt=10.^app.sldLagTime.Value;
                    [~,ind]=min(abs(app.msd(:,1)-dt));

                    cla(app.axePlot,'reset')
                    app.axePlot.Box='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot,'on')
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot,'off')


                    if ~isempty(app.van_Hove_rec)
                        [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                        if ind2==ind
                            hold(app.axePlot,'on');
                            plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                            hold(app.axePlot,'off');
                        end
                    end

                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';


                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='Δ\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';

                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                    tx.Interpreter='tex';

                case app.optSPMSD
                    cla(app.axePlot,'reset');
                    app.axePlot.Box='on';

                    legend(app.axePlot,'off');

                    co=app.axePlot.ColorOrder;
                    for i=1:length(co(:,1))
                        co_2=rgb2hsv(co(i,:));
                        co_2(3)=co_2(3)+(1-co_2(3))*3/4;
                        co(i,:)=hsv2rgb(co_2);
                    end
                    app.axePlot.ColorOrder=co;


                    hold(app.axePlot,'on')
                    cellfun(@(x) plot(app.axePlot,x(:,1),x(:,2)),app.msd_i,'UniformOutput',0);
                    app.axePlot.XScale='log';
                    app.axePlot.YScale='log';

                    loglog(app.axePlot,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1)
                    hold(app.axePlot,'off');

                    app.axePlot.XLabel.String='lag time (s)';
                    app.axePlot.YLabel.String='MSD (m^2\rm)';
                    app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                    app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
                    app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                    app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';


            end
        end

        % Value changed function: sldLagTime
        function sldLagTimeValueChanged(app, event)
            value = app.sldLagTime.Value;
            if ~isempty(app.msd)
                slider_value=10.^value;
                [~,ind]=min(abs(app.msd(:,1)-slider_value));
                app.sldLagTime.Value=log10(app.msd(ind,1));
                drawnow

                if app.optVanHove.Value
                    dt=10.^value;
                    [~,ind]=min(abs(app.msd(:,1)-dt));

                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot)
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot)


                    if ~isempty(app.van_Hove_rec)
                        [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                        if ind2==ind
                            hold(app.axePlot,'on');
                            plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                            hold(app.axePlot,'off');
                        end
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            t=app.tblCurrentTracks.Data;
            if isempty(app.CurrentSelectedRow)||app.CurrentSelectedRow>length(t(:,1))

                uialert(app.UIFigure,'Selet a row first.','No row selected.','Icon','warning')
                return
            end


            t(app.CurrentSelectedRow,:)=[];


            app.tblCurrentTracks.Data=t;
        end

        % Cell selection callback: tblCurrentTracks
        function tblCurrentTracksCellSelection(app, event)
            indices = event.Indices;
            app.CurrentSelectedRow=indices(1);
        end

        % Button pushed function: btnLucy
        function btnLucyButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end
            dt=10.^app.sldLagTime.Value;


            app.btnLucy.Enable='off';
            app.Lucy_dialogBox=Lucy_dlg(app,app.msd,app.van_Hove,dt);
            %
            %
            %             [~,l]=min(abs(app.msd(:,1)-dt));
            %             x=(app.van_Hove{l,2}(1:end-1)+mean(diff(app.van_Hove{l,2}))./2)';
            %
            %             dx=diff(app.van_Hove{l,2});
            %             dx=dx(:);
            %             phi_tilde=app.van_Hove{l,1}'.*dx;
            %
            %             D_est=app.msd(l,2)./(2.*dt);
            %
            %             % modify the range of D here
            %             xi_bin=logspace(round(log10(D_est)-3),round(log10(D_est)+3),1001);
            %
            %             xi_bin=xi_bin(:);
            %             dxi=diff(xi_bin);
            %             dxi=dxi(:);
            %             xi=xi_bin(1:end-1)+dxi./2;
            %             xi=xi(:);
            %
            %             psi=(xi./D_est.^2).*exp(-xi.^2./(2.*D_est.^2)).*dxi;
            %             psi=psi./sum(psi)
            %
            %             % phi0=zeros(length(x),5000);
            %             % Q=zeros(length(x),5000);
            %             % psi0=phi0;
            %             % P=zeros(length(x),length(xi));
            %
            %             % for j=1:length(xi)
            %             %     P(:,j)=(1./sqrt(4.*pi.*xi(j).*dt)).*exp(-x.^2./(4.*xi(j).*dt)).*dx;
            %             % end
            % % construct the kernel matrix P
            % [XI, X] = meshgrid(xi, x);
            % P = (1 ./ sqrt(4 .* pi .* XI .* dt)) .* exp(-X.^2 ./ (4 .* XI .* dt)) .* dx;
            %
            %
            %             change=100;
            %
            %
            %             while change>0.01 % modify the escaping condition here
            %                 for j=1:length(xi)
            %
            %                     phi0(:,j)=psi(j).*P(:,j);
            %
            %                 end
            %                 phi=sum(phi0,2);
            %                 for j=1:length(xi)
            %                     Q(:,j)=psi(j).*P(:,j)./phi;
            %                 end
            %                 for k=1:length(x)
            %                     psi0(k,:)=phi_tilde(k).*Q(k,:);
            %                 end
            %                 psi_old=psi;
            %                 psi=sum(psi0,1);
            %                 psi=psi(:);
            %                 psi(psi<10e-324)=5e-324;
            %                 psi_old=psi_old(:);
            %                 temp=abs(psi_old-psi)./psi;
            %                 temp=temp(temp~=inf);
            %                 change=mean(temp,'omitnan');
            %
            %                 app.lblStatus.Text=sprintf('%.3e %',...
            %                     change-0.01);
            %                 drawnow;
            %
            %             end % set break point here
            %
            %
            %             app.p_D=[xi,psi./dxi]; % distribution density (bin-width dependent)
            %             app.P_D=[xi,psi]; % distribution
            %             P_com=phi./dx;
            %             app.dtP=dt;
            %             app.van_Hove_rec=[app.van_Hove{l,2}(1:end-1)',P_com(:,1)]; % reconstructed van_Hove
            %
            %
            %             cla(app.axePlot,'reset');
            %             app.axePlot.Box='on';
            %             app.axePlot.XMinorTick='on';
            %             app.axePlot.YMinorTick='on';
            %
            %             legend(app.axePlot,'off');
            %             yyaxis(app.axePlot,'left');
            %             area(app.axePlot,log10(app.P_D(:,1)),app.P_D(:,2),'LineStyle','none');
            %             ylabel(app.axePlot,'\itP\rm(\itD\rm)')
            %             yyaxis(app.axePlot,'right');
            %             plot(app.axePlot,log10(app.P_D(:,1)),cumsum(app.P_D(:,2)),'-','LineWidth',1);
            %             app.axePlot.YTickLabel=[];
            %             app.axePlot.XLabel.String='log(\itD\rm (m^2/s))';
            %             tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' \Delta\it t\rm = ' sprintf('%.2e',app.msd(l,1)) ' s']);
            %             tx.Interpreter='tex';
            %             tx.VerticalAlignment='top';
            %
            %             app.lblStatus.Text='Done!';
            %             drawnow


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'No MSD data to save.','Blank data','Icon','info');
                return
            end
            path=uigetdir(app.CurrentPath,'Select folder for saving data');
            if path~=0


                msd_i=app.msd_i;
                msd=app.msd;
                G=app.G;
                alpha_2=app.alpha_2;
                alpha_2_i=app.alpha_2_i;
                van_Hove=app.van_Hove;

                save([path '\msd_data.mat'], ...
                    'msd_i','msd','G','alpha_2',...
                    'alpha_2','alpha_2_i','van_Hove');

                app.CurrentPath=[path '\'];
                if isempty(app.P_D)
                    uialert(app.UIFigure,'No P(D) data to save.','Blank data','Icon','info');
                    return
                end

                p_D=app.p_D;
                P_D=app.P_D;
                van_Hove_rec=app.van_Hove_rec;
                save([path '\PD_data_' sprintf('%.2e',app.dtP),'.mat'],...
                    'p_D','P_D','van_Hove_rec');
                app.CurrentPath=[path '\'];
            else
                uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                figure(app.UIFigure);
                return
            end
        end

        % Button pushed function: btnGSEROpt
        function btnGSEROptButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end

            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.GSER_dialogBox)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Track Analysis // Weixiang Sun // mswxsun@scut.edu.cn';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axePlot = uiaxes(app.UIFigure);
            xlabel(app.axePlot, 'X')
            ylabel(app.axePlot, 'Y')
            app.pnlParameters = uipanel(app.UIFigure);
            app.pnlParameters.Title = 'Parameters';
            app.pnlParameters.Tag = 'pnlParameters';
            app.TemperatureCLabel = uilabel(app.pnlParameters);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.edtTemperature = uieditfield(app.pnlParameters, 'numeric');
            app.edtTemperature.Limits = [0 Inf];
            app.edtTemperature.ValueChangedFcn = createCallbackFcn(app, @edtTemperatureValueChanged, true);
            app.edtTemperature.Value = 30;
            app.ParticleradiusmLabel = uilabel(app.pnlParameters);
            app.ParticleradiusmLabel.Text = 'Particle radius (μm):';
            app.edtParticleRadius = uieditfield(app.pnlParameters, 'numeric');
            app.edtParticleRadius.Limits = [0 Inf];
            app.edtParticleRadius.ValueChangedFcn = createCallbackFcn(app, @edtParticleRadiusValueChanged, true);
            app.edtParticleRadius.Value = 0.1;
            app.ShuttertimesLabel = uilabel(app.pnlParameters);
            app.ShuttertimesLabel.Text = 'Shutter time (s):';
            app.edtShutterTime = uieditfield(app.pnlParameters, 'numeric');
            app.edtShutterTime.Limits = [0 Inf];
            app.edtShutterTime.ValueChangedFcn = createCallbackFcn(app, @edtShutterTimeValueChanged, true);
            app.edtShutterTime.Value = 0.020142;
            app.ResolutionmpixelLabel = uilabel(app.pnlParameters);
            app.ResolutionmpixelLabel.Text = 'Resolution (μm/pixel):';
            app.edtResolution = uieditfield(app.pnlParameters, 'numeric');
            app.edtResolution.Limits = [0 Inf];
            app.edtResolution.ValueChangedFcn = createCallbackFcn(app, @edtResolutionValueChanged, true);
            app.edtResolution.Value = 0.1083266701;
            app.btnAddTrack = uibutton(app.UIFigure, 'push');
            app.btnAddTrack.ButtonPushedFcn = createCallbackFcn(app, @btnAddTrackButtonPushed, true);
            app.btnAddTrack.Text = 'Add...';
            app.tblCurrentTracks = uitable(app.UIFigure);
            app.tblCurrentTracks.ColumnName = '';
            app.tblCurrentTracks.CellSelectionCallback = createCallbackFcn(app, @tblCurrentTracksCellSelection, true);
            app.btnCalculate = uibutton(app.UIFigure, 'push');
            app.btnCalculate.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateButtonPushed, true);
            app.btnCalculate.Text = 'Calculate';
            app.bgpPlotData = uibuttongroup(app.UIFigure);
            app.bgpPlotData.SelectionChangedFcn = createCallbackFcn(app, @bgpPlotDataSelectionChanged, true);
            app.bgpPlotData.Title = 'Plot data';
            app.optMPMSD = uiradiobutton(app.bgpPlotData);
            app.optMPMSD.Text = 'MSD';
            app.optMPMSD.Value = true;
            app.optGSER = uiradiobutton(app.bgpPlotData);
            app.optGSER.Text = 'Modulus';
            app.optAlpha_2 = uiradiobutton(app.bgpPlotData);
            app.optAlpha_2.Text = 'alpha_2';
            app.optVanHove = uiradiobutton(app.bgpPlotData);
            app.optVanHove.Text = 'van Hove';
            app.optSPMSD = uiradiobutton(app.bgpPlotData);
            app.optSPMSD.Text = 'SP-MSD';
            app.LagtimesLabel = uilabel(app.UIFigure);
            app.LagtimesLabel.Text = 'Lag time (s):';
            app.sldLagTime = uislider(app.UIFigure);
            app.sldLagTime.ValueChangedFcn = createCallbackFcn(app, @sldLagTimeValueChanged, true);
            app.btnLucy = uibutton(app.UIFigure, 'push');
            app.btnLucy.ButtonPushedFcn = createCallbackFcn(app, @btnLucyButtonPushed, true);
            app.btnLucy.Text = 'Lucy';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Text = 'Clear';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save...';
            app.TrackfilesmatLabel = uilabel(app.UIFigure);
            app.TrackfilesmatLabel.Text = {'Track files'; '(*.mat):'};
            app.lblStatus = uilabel(app.UIFigure);
            app.lblStatus.Text = '';
            app.btnGSEROpt = uibutton(app.UIFigure, 'push');
            app.btnGSEROpt.ButtonPushedFcn = createCallbackFcn(app, @btnGSEROptButtonPushed, true);
            app.btnGSEROpt.Text = 'GSER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_anal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end